classdef WaveTankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        WaveControlTab                matlab.ui.container.Tab
        WaveTypeButtonGroup           matlab.ui.container.ButtonGroup
        Button3                       matlab.ui.control.RadioButton
        Button2                       matlab.ui.control.RadioButton
        TypButton                     matlab.ui.control.RadioButton
        PowerSlider                   matlab.ui.control.Slider
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ControlWave                   matlab.ui.control.UIAxes
        WaveAnalysisTab               matlab.ui.container.Tab
        AnalysisWave                  matlab.ui.control.UIAxes
        WaveCalibrationTab            matlab.ui.container.Tab
        ExportSettingsButton          matlab.ui.control.Button
        LoadSettingsButton            matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ViewBoxWidth                  matlab.ui.control.Slider
        HorizontalSlider_3Label       matlab.ui.control.Label
        ViewBoxHeight                 matlab.ui.control.Slider
        HeightLabel                   matlab.ui.control.Label
        ViewBoxSizeLabel              matlab.ui.control.Label
        EnableSwitchLabel             matlab.ui.control.Label
        EnableSwitch                  matlab.ui.control.Switch
        LineViewLabel                 matlab.ui.control.Label
        AIVerticalPosition            matlab.ui.control.Slider
        VerticalSlider_2Label         matlab.ui.control.Label
        AIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSlider_2Label       matlab.ui.control.Label
        AfterImpactBoxLabel           matlab.ui.control.Label
        BIVerticalPosition            matlab.ui.control.Slider
        HorizontalLabel               matlab.ui.control.Label
        BIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSliderLabel         matlab.ui.control.Label
        BeforeImpactBoxPositionLabel  matlab.ui.control.Label
        AICalibration                 matlab.ui.control.UIAxes
        BICalibration                 matlab.ui.control.UIAxes
        FullCalibrationWave           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reading the video and starting the videoplayer
            originalVideo = vision.VideoFileReader("wave_03.mov")
            videoPlayer = vision.VideoPlayer;
            %Crop of whole image
            crop1 = 500;
            crop2 = 600; 
            crop3 = 1100;
            crop4 = 275;
            % Setting up sliders
            [heightFullIMG,widthFullIMG, dimensions] = size(originalVideo());
            app.ViewBoxWidth.Limits = [0 widthFullIMG];
            app.BIHorizontalPosition.Limits = [0 widthFullIMG];
            app.AIHorizontalPosition.Limits = [0 widthFullIMG];
            app.ViewBoxHeight.Limits = [0 heightFullIMG];
            app.BIVerticalPosition.Limits = [0 heightFullIMG];
            app.AIVerticalPosition.Limits = [0 heightFullIMG];
            
            [cropHeight,cropWidth, dimensions] = size(imcrop(originalVideo(), [crop1,crop2,crop3,crop4]));
            app.ViewBoxWidth.Limits = [0 crop3];            %Good
            app.BIHorizontalPosition.Limits = [0 cropWidth];    
            app.AIHorizontalPosition.Limits = [0 cropWidth];    
            app.ViewBoxHeight.Limits = [0 crop4];           %Good
            app.BIVerticalPosition.Limits = [0 cropHeight];
            app.AIVerticalPosition.Limits = [0 cropHeight];
            
            i = 0; 
            while 0 < 1
                % Showing the original, unedited video
                videoPlayer(originalVideo)                

                %% Showing and editing the edited video
                %Creates the mask of the wave
                BWWave = edge(createMask(imgaussfilt(imcrop(originalVideo(), [crop1,crop2,crop3,crop4]),3)), 'canny');
                
                %No idea what this is for and what it does but it works
                row = nan(1,size(BWWave,2));
                [heightIMG,widthIMG] = size(BWWave);
                line = zeros(heightIMG, widthIMG);

                js = zeros(1, widthIMG-2);
                ls = zeros(1, widthIMG-2);
                for j = 1:widthIMG; 
                    for l = 1:heightIMG %figure out how to get height
                        if BWWave(l,j) == 1
                            line(l, j) = 1;
                            js(j-1) = j;
                            ls(j-1) = l;
                            break
                        end
                    end
                end
                row = fillmissing(row, 'previous');
                % Finding height of the highest bit of water.
                % May want to include this, as well as a way of getting the
                % calm level of water
                for k = 1:widthIMG; 
                    try 
                        row(k) = find(BWWave(:,k),1,'first');
                        [lowY, corX] = min(row);
                        [highY, corXHigh] = max(row);
                    end    
                end
                highLvl = lowY;

                editedVideo = BWWave + imcrop(originalVideo(), [crop1,crop2,crop3,crop4]);
                editedVideo = insertShape(editedVideo, 'circle', [corX lowY 5], 'LineWidth', 10);
                editedVideo = insertShape(editedVideo,'line', [0 highLvl widthIMG+1 highLvl], 'LineWidth', 2, 'Color', 'red');
                % Draws the two boxes on the image preview
                editedVideoWithBoxes = insertShape(editedVideo, 'filled-rectangle', [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Red');
                editedVideoWithBoxes = insertShape(editedVideoWithBoxes, 'filled-rectangle', [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Blue');
                
                imshow(editedVideoWithBoxes, 'Parent', app.AnalysisWave)
                imshow(editedVideoWithBoxes, 'Parent', app.ControlWave)
                imshow(editedVideoWithBoxes, 'Parent', app.FullCalibrationWave)
                % Displaying the image inside the before impact box (red)
                imshow(imcrop(editedVideo, [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]), 'Parent', app.BICalibration)
                % Displaying the image inside the after impact box (blue)
                imshow(imcrop(editedVideo, [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]), 'Parent', app.AICalibration)
            
                % Loops the video when it finishes
                if isDone(originalVideo)
                    originalVideo = vision.VideoFileReader("wave_03.mov")
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveControlTab = uitab(app.TabGroup);
            app.WaveControlTab.Title = 'Wave Control';
            app.ControlWave = uiaxes(app.WaveControlTab);
            title(app.ControlWave, 'Wave')
            app.ControlWave.XTick = [];
            app.ControlWave.YTick = [];
            app.StartButton = uibutton(app.WaveControlTab, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.WaveControlTab, 'push');
            app.StopButton.Text = 'Stop';
            app.PowerSlider = uislider(app.WaveControlTab);
            app.WaveTypeButtonGroup = uibuttongroup(app.WaveControlTab);
            app.WaveTypeButtonGroup.Title = 'Wave Type';
            app.TypButton = uiradiobutton(app.WaveTypeButtonGroup);
            app.TypButton.Text = 'Typ';
            app.TypButton.Value = true;
            app.Button2 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button3.Text = 'Button3';
            app.WaveAnalysisTab = uitab(app.TabGroup);
            app.WaveAnalysisTab.Title = 'Wave Analysis';
            app.AnalysisWave = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisWave, 'Wave')
            app.AnalysisWave.XTick = [];
            app.AnalysisWave.YTick = [];
            app.WaveCalibrationTab = uitab(app.TabGroup);
            app.WaveCalibrationTab.Title = 'Wave Calibration';
            app.FullCalibrationWave = uiaxes(app.WaveCalibrationTab);
            title(app.FullCalibrationWave, 'Full Wave')
            app.FullCalibrationWave.XTick = [];
            app.FullCalibrationWave.YTick = [];
            app.BICalibration = uiaxes(app.WaveCalibrationTab);
            title(app.BICalibration, {'Before Impact'; ''})
            app.BICalibration.XTick = [];
            app.BICalibration.YTick = [];
            app.AICalibration = uiaxes(app.WaveCalibrationTab);
            title(app.AICalibration, {'After Impact'; ''})
            app.AICalibration.XTick = [];
            app.AICalibration.YTick = [];
            app.BeforeImpactBoxPositionLabel = uilabel(app.WaveCalibrationTab);
            app.BeforeImpactBoxPositionLabel.Text = 'Before Impact Box Position';
            app.HorizontalSliderLabel = uilabel(app.WaveCalibrationTab);
            app.HorizontalSliderLabel.Text = {'Horizontal'; ''};
            app.BIHorizontalPosition = uislider(app.WaveCalibrationTab);
            app.BIHorizontalPosition.Limits = [0 1920];
            app.BIHorizontalPosition.Value = 800;
            app.HorizontalLabel = uilabel(app.WaveCalibrationTab);
            app.HorizontalLabel.Text = 'Vertical';
            app.BIVerticalPosition = uislider(app.WaveCalibrationTab);
            app.BIVerticalPosition.Limits = [0 1080];
            app.BIVerticalPosition.Value = 80;
            app.AfterImpactBoxLabel = uilabel(app.WaveCalibrationTab);
            app.AfterImpactBoxLabel.Text = 'After Impact Box';
            app.HorizontalSlider_2Label = uilabel(app.WaveCalibrationTab);
            app.HorizontalSlider_2Label.Text = {'Horizontal'; ''};
            app.AIHorizontalPosition = uislider(app.WaveCalibrationTab);
            app.AIHorizontalPosition.Limits = [0 1920];
            app.AIHorizontalPosition.Value = 200;
            app.VerticalSlider_2Label = uilabel(app.WaveCalibrationTab);
            app.VerticalSlider_2Label.Text = 'Vertical';
            app.AIVerticalPosition = uislider(app.WaveCalibrationTab);
            app.AIVerticalPosition.Limits = [0 1080];
            app.AIVerticalPosition.Value = 80;
            app.LineViewLabel = uilabel(app.WaveCalibrationTab);
            app.LineViewLabel.Text = 'Line View';
            app.EnableSwitch = uiswitch(app.WaveCalibrationTab, 'slider');
            app.EnableSwitchLabel = uilabel(app.WaveCalibrationTab);
            app.EnableSwitchLabel.Text = 'Enable ';
            app.ViewBoxSizeLabel = uilabel(app.WaveCalibrationTab);
            app.ViewBoxSizeLabel.Text = 'View Box Size';
            app.HeightLabel = uilabel(app.WaveCalibrationTab);
            app.HeightLabel.Text = 'Height';
            app.ViewBoxHeight = uislider(app.WaveCalibrationTab);
            app.ViewBoxHeight.Limits = [0 1080];
            app.ViewBoxHeight.Value = 90;
            app.HorizontalSlider_3Label = uilabel(app.WaveCalibrationTab);
            app.HorizontalSlider_3Label.Text = {'Horizontal'; ''};
            app.ViewBoxWidth = uislider(app.WaveCalibrationTab);
            app.ViewBoxWidth.Limits = [0 1920];
            app.ViewBoxWidth.Value = 280;
            app.ResetButton = uibutton(app.WaveCalibrationTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.LoadSettingsButton = uibutton(app.WaveCalibrationTab, 'push');
            app.LoadSettingsButton.Text = 'Load Settings';
            app.ExportSettingsButton = uibutton(app.WaveCalibrationTab, 'push');
            app.ExportSettingsButton.Text = 'Export Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveTankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end